classdef aplicacionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ArchivoMenu                     matlab.ui.container.Menu
        AbrirArchivoMenu                matlab.ui.container.Menu
        GuardarMenu                     matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        OperacionesBsicasMenu           matlab.ui.container.Menu
        EcualizacionMenu                matlab.ui.container.Menu
        EcualizarMenu                   matlab.ui.container.Menu
        MostrarhistogramasMenu          matlab.ui.container.Menu
        InversinMenu                    matlab.ui.container.Menu
        BinariaMenu                     matlab.ui.container.Menu
        FotogrficaMenu                  matlab.ui.container.Menu
        AdicinMenu                      matlab.ui.container.Menu
        SustraccinMenu                  matlab.ui.container.Menu
        EspejoMenu                      matlab.ui.container.Menu
        FiltrosMenu                     matlab.ui.container.Menu
        FiltradoEspacialOrdenEstadsticoMenu  matlab.ui.container.Menu
        MedianaMenu                     matlab.ui.container.Menu
        ModaMenu                        matlab.ui.container.Menu
        MximoMenu                       matlab.ui.container.Menu
        MnimoMenu                       matlab.ui.container.Menu
        OrdenNMenu                      matlab.ui.container.Menu
        FiltradoEspacialPasoBajasMenu   matlab.ui.container.Menu
        CajaoMediaMenu                  matlab.ui.container.Menu
        GaussianoMenu                   matlab.ui.container.Menu
        FiltradoEspacialPasoAltasMenu   matlab.ui.container.Menu
        LaplacianoMenu                  matlab.ui.container.Menu
        VecinosMenu                     matlab.ui.container.Menu
        VecinosMenu_2                   matlab.ui.container.Menu
        DeteccindecontornosMenu         matlab.ui.container.Menu
        PrewittMenu                     matlab.ui.container.Menu
        SobelMenu                       matlab.ui.container.Menu
        RobertsMenu                     matlab.ui.container.Menu
        ModificaciondelColordeOjosMenu  matlab.ui.container.Menu
        AyudaMenu                       matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        RotarIzquierda                  matlab.ui.container.toolbar.PushTool
        RotarDerecha                    matlab.ui.container.toolbar.PushTool
        Deshacer                        matlab.ui.container.toolbar.PushTool
        DeshacerTodo                    matlab.ui.container.toolbar.PushTool
        QuitarImagen                    matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        EdicinprincipalTab              matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ConvertirDropDown               matlab.ui.control.DropDown
        ConvertirDropDownLabel          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        GeneracindecollageTab           matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxesImg1Collage               matlab.ui.control.UIAxes
        UIAxesImg2Collage               matlab.ui.control.UIAxes
        UIAxesImg3Collage               matlab.ui.control.UIAxes
        UIAxesImg4Collage               matlab.ui.control.UIAxes
        UIAxesImg5Collage               matlab.ui.control.UIAxes
        UIAxesImg6Collage               matlab.ui.control.UIAxes
        UIAxesImg7Collage               matlab.ui.control.UIAxes
        UIAxesImg8Collage               matlab.ui.control.UIAxes
        UIAxesResCollage                matlab.ui.control.UIAxes
    end

        properties (Access = private)
        %Matrices de las imagenes

        imgOri = [] % imagen original
        mapOri = [] % mapa de color de la imagen original
        typeOri = [] % tipo de imágenes
        pathOri = [] % Ruta de imágenes

        % Matrices de la imagen procesada

        imgPro = [] % imagen procesada
        mapPro = [] % mapa de color de la imagen procesada
        typePro = [] % tipo de imágnes
        pathPro = [] % Ruta de imágenes

         %Matrices de las imagenes del apartado de adición y sustracción

        imgOri2 = [] % imagen original 2
        mapOri2 = [] % mapa de color de la imagen original 2
        typeOri2 = [] % tipo de imágenes 2
        pathOri2 = [] % Ruta de imágenes 2

        %Declarar las funciones que va a deshacer

        imgDes = []
        mapDes = []
        typeDes = []

        % Segunda Imagen
        imgSeg = [] 
        mapSeg = [] 
        typeSeg = [] 
        pathSeg = [] 

        % Histogramas

        histOriginal   = []
        histEcualizado = []
        imgEcualizada  = []

        % elemento estructurante
        elemento
        % coordenadas segmentacion por crecimiento de regiones
        coordenadaX
        coordenadaY
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: AbrirArchivoMenu
        function AbrirArchivo(app, event)
            %Llamar la funcion abrir imagen
            [img, map, type] = AbrirImagen();
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.imgOri  = img;
            app.mapOri  = map;
            app.typeOri = type;
                if app.typeOri == "indexed"
                    imshow(app.imgOri, app.mapOri, 'Parent', app.UIAxes);
                else
                    imshow(app.imgOri, 'Parent', app.UIAxes);

                end
            
            %Crear copia de las modificaciones
            app.imgPro  = app.imgOri;
            app.mapPro  = app.mapOri;
            app.typePro = app.typeOri;
                if app.typePro == "indexed"
                    imshow(app.imgPro, app.mapPro, 'Parent', app.UIAxes2);
                else
                    imshow(app.imgPro, 'Parent', app.UIAxes2);
                end

            % Pasar la variable que va a deshacer
            app.imgDes  = app.imgOri;
            app.mapDes  = app.mapOri;
            app.typeDes = app.typeOri;
            
        end

        % Menu selected function: GuardarMenu
        function GuardarMenuSelected(app, event)
            %Guardar la imagen procesada
            Guardar(app.imgPro, app.mapPro, app.typePro);
        end

        % Value changed function: ConvertirDropDown
        function CambioDeFormato(app, event)
            if isempty(app.imgPro), return, end

            selection = app.ConvertirDropDown.Value;

            if selection=="Selecciona El Tipo", return, end
            
            % Conversion de la imagen
            [imgCon, mapCon, typeCon] = Convertir(selection, app.imgPro, app.mapPro, app.typePro);

            if ~isequal(app.typePro, typeCon)
                
                    % Actualizar imágenes y mostrar
                    UpdateImagenes(app, imgCon, mapCon, typeCon);
            end

            function UpdateImagenes(app, img, map, type)
            %Actualiza las imagenes en la aplicación

                app.imgDes = app.imgPro;
                app.mapDes = app.mapPro;
                app.typeDes = app.typePro;

                app.imgPro  = img;
                app.mapPro  = map;
                app.typePro = type;

                switch type
                    case "indexed"
                    imshow(img, map, 'Parent', app.UIAxes2);
                    otherwise
                    imshow(app.imgPro, 'Parent', app.UIAxes2);
                end
            end
        end

        % Callback function: QuitarImagen
        function QuitarImagenClicked(app, event)
       
            % Quitar imagen original 
            cla(app.UIAxes, 'reset');
            app.UIAxes.Title.String = "Imagen original";
            app.UIAxes.XLabel.String = "X";
            app.UIAxes.YLabel.String = "Y";
            app.imgOri  = [];
            app.mapOri  = [];
            app.pathOri = [];
            app.typeOri = [];

            % Quitar imagen procesada
            cla(app.UIAxes2, 'reset');
            app.UIAxes2.Title.String = "Imagen procesada";
            app.UIAxes2.XLabel.String = "X";
            app.UIAxes2.YLabel.String = "Y";
            app.imgPro  = [];
            app.mapPro  = [];
            app.pathPro = [];
            app.typePro = [];

            % Quitar imagen original para adición y sustracción
            cla(app.UIAxes3, 'reset');
            app.UIAxes3.Title.String = "Imagen procesada";
            app.UIAxes3.XLabel.String = "X";
            app.UIAxes3.YLabel.String = "Y";
            app.imgOri2  = [];
            app.mapOri2  = [];
            app.pathOri2 = [];
            app.typeOri2 = [];

            % Quitar imagen procesada (deshacer)
            app.imgDes = [];
            app.mapDes = [];
            app.typeDes = [];
        end

        % Callback function: Deshacer
        function DeshacerClicked(app, event)
            if isempty(app.imgPro), return, end

            app.imgPro = app.imgDes;
            app.mapPro  = app.mapDes;
            app.typePro = app.typeDes;

            imshow(app.imgPro, 'Parent', app.UIAxes2);
        end

        % Menu selected function: EcualizarMenu
        function EcualizarMenuSelected(app, event)
            if isempty(app.imgPro), return, end

            try
                [imgEc, histOri, histEc] = Ecualizar(app.imgPro, app.typePro);

                % Actualización de imagen anterior
                app.imgDes  = app.imgPro;
                app.mapDes  = app.mapPro;
                app.typeDes = app.typePro;
    
                % Ecualización
                app.imgPro   = imgEc;
                app.histOriginal   = histOri;
                app.histEcualizado = histEc;
                app.imgEcualizada  = imgEc;
                imshow(app.imgPro, 'Parent', app.UIAxes2);

            catch mensajeError
                msgbox(mensajeError.message);
                return
            end
        end

        % Menu selected function: MostrarhistogramasMenu
        function MostrarhistogramasMenuSelected(app, event)
            if isempty(app.imgPro), return, end
            
            try
                MostrarHistogramas(app.histOriginal, app.histEcualizado, app.imgPro, app.imgEcualizada);
            catch mensajeError
                msgbox(mensajeError.message);
                return
            end
        end

        % Menu selected function: BinariaMenu
        function BinariaMenuSelected(app, event)
             if isempty(app.imgPro), return, end

            try
                % Inversion binaria
                imgBinaria = InversionBinaria(app.imgPro, app.typePro);
                % Actualizacion de imagen anterior (para usar deshacer)
                app.imgDes  = app.imgPro;
                app.mapDes  = app.mapPro;
                app.typeDes = app.typePro;
                % se cargan cambios en imagen procesada y se muestra
                app.imgPro = imgBinaria;
                imshow(app.imgPro, 'Parent', app.UIAxes2);

            catch mensajeError
                msgbox(mensajeError.message);
                return
            end 
        end

        % Callback function: DeshacerTodo
        function DeshacerTodoClicked(app, event)
            if isempty(app.imgPro), return, end

            app.imgPro  = app.imgOri;
            app.mapPro  = app.mapOri;
            app.typePro = app.typeOri;
            
            imshow(app.imgPro, 'Parent', app.UIAxes2);
        end

        % Menu selected function: FotogrficaMenu
        function FotogrficaMenuSelected(app, event)
            if isempty(app.imgPro), return, end

            try
                % Inversion binaria
                imgInvertida = InversionFotografica(app.imgPro, app.typePro);
                % Actualizacion de imagen anterior (para usar deshacer)
                app.imgDes  = app.imgPro;
                app.mapDes  = app.mapPro;
                app.typeDes = app.typePro;
                % se cargan cambios en imagen procesada y se muestra
                app.imgPro = imgInvertida;
                imshow(app.imgPro, 'Parent', app.UIAxes2);

            catch mensajeError
                msgbox(mensajeError.message);
                return
            end
        end

        % Menu selected function: AdicinMenu
        function AdicionMenuSelected(app, event)
            % Verificar si la imagen procesada está vacía
            if isempty(app.imgPro)
                return;
            end
            
            try
                % Abrir una imagen adicional
                [img, map, path, type] = abrirImagen();

                % Verificar si la apertura de la imagen fue cancelada
                if isempty(img)
                    return;
                end
            
                % Almacenar información de la segunda imagen en propiedades de la aplicación
                app.imgSeg = img;
                app.mapSeg = map;
                app.pathSeg = path;
                app.typeSeg = type;
            
                % Mostrar la segunda imagen en la GUI
                if app.typeSeg == "indexed"
                    imshow(app.imgSeg, app.mapSeg, 'Parent', app.UIAxes3);
                else
                    imshow(app.imgSeg, 'Parent', app.UIAxes3);
                end
            
                % Sumar la imagen procesada y la segunda imagen
                imgAdi = Adicion(app.imgPro, app.typePro, app.imgSeg, app.typeSeg);
            
                % Actualizar la imagen anterior para usar deshacer
                app.imgDes = app.imgPro;
                app.mapDes = app.mapPro;
                app.typeDes = app.typePro;
            
                % Cargar los cambios en la imagen procesada y mostrarla en la GUI
                app.imgPro = imgAdi;
                imshow(app.imgPro, 'Parent', app.UIAxes2);
            
            catch ME
                % Manejar errores específicos
                if strcmp(ME.identifier, 'Image:TypeError')
                    % Manejar el error de tipo de imagen no admitido
                    errordlg('Operación no válida para imágenes indexadas.', 'Error');
                else
                    % Otros errores no manejados específicamente
                    errordlg(ME.message, 'Error');
                end
            end
        end

        % Callback function: RotarIzquierda
        function RotarIzquierdaClicked(app, event)
            imgRotadaIzq = RotacionIzquierda(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgRotadaIzq, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgRotadaIzq;
        end

        % Callback function: RotarDerecha
        function RotarDerechaClicked(app, event)
            imgRotadaDer = RotacionDerecha(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgRotadaDer, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgRotadaDer;
        end

        % Menu selected function: EspejoMenu
        function EspejoMenuSelected(app, event)
            imgConEspejo = Espejo(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgConEspejo, 'Parent', app.UIAxes2);
            
            app.imgDes  = app.imgPro;
            app.imgPro = imgConEspejo; 
        end

        % Menu selected function: ModaMenu
        function ModaMenuSelected(app, event)
            imgModa = FiltroModa(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgModa, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgModa; 
        end

        % Menu selected function: MedianaMenu
        function MedianaMenuSelected(app, event)
            imgMediana = FiltroMediana(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgMediana, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgMediana;
        end

        % Menu selected function: MximoMenu
        function MximoMenuSelected(app, event)
            imgMaximo = FiltroMaximo(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgMaximo, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgMaximo;
        end

        % Menu selected function: MnimoMenu
        function MnimoMenuSelected(app, event)
            imgMinimo = FiltroMinimo(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgMinimo, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgMinimo;
        end

        % Menu selected function: CajaoMediaMenu
        function CajaoMediaMenuSelected(app, event)
            imgCaja = FiltroMedia(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgCaja, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgCaja;
        end

        % Menu selected function: GaussianoMenu
        function GaussianoMenuSelected(app, event)
            imgGauss = FiltroGaussiano(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgGauss, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgGauss;
        end

        % Menu selected function: VecinosMenu
        function VecinosMenuSelected(app, event)
            imgLap4v = FiltroLaplaciano4Vecinos(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgLap4v, 'Parent', app.UIAxes2);
            
            app.imgDes  = app.imgPro;
            app.imgPro = imgLap4v;
        end

        % Menu selected function: VecinosMenu_2
        function VecinosMenu_2Selected(app, event)
            imgLap8v = FiltroLaplaciano8Vecinos(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgLap8v, 'Parent', app.UIAxes2);
            
            app.imgDes  = app.imgPro;
            app.imgPro = imgLap8v;
        end

        % Menu selected function: PrewittMenu
        function PrewittMenuSelected(app, event)
            imgPrewitt = FiltroPrewitt(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgPrewitt, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgPrewitt;
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            imgSobel = FiltroSobel(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(imgSobel, 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgSobel; 
        end

        % Menu selected function: RobertsMenu
        function RobertsMenuSelected(app, event)
            imgRoberts = FiltroRoberts(app.imgPro);
            
            % Colocar imagen en UIAxes principal
            imshow(cat(3, imgRoberts, []), 'Parent', app.UIAxes2);
            app.imgDes = app.imgPro;
            app.imgPro = imgRoberts;
        end

        % Menu selected function: OrdenNMenu
        function OrdenNMenuSelected(app, event)

        end

        % Menu selected function: SustraccinMenu
        function SustraccinMenuSelected(app, event)
             % Verificar si la imagen procesada está vacía
            if isempty(app.imgPro)
                return;
            end
            
            try
                % Abrir una imagen adicional
                [img, map, path, type] = abrirImagen();

                % Verificar si la apertura de la imagen fue cancelada
                if isempty(img)
                    return;
                end
            
                % Almacenar información de la segunda imagen en propiedades de la aplicación
                app.imgSeg = img;
                app.mapSeg = map;
                app.pathSeg = path;
                app.typeSeg = type;
            
                % Mostrar la segunda imagen en la GUI
                if app.typeSeg == "indexed"
                    imshow(app.imgSeg, app.mapSeg, 'Parent', app.UIAxes3);
                else
                    imshow(app.imgSeg, 'Parent', app.UIAxes3);
                end
            
                % Sumar la imagen procesada y la segunda imagen
                imgAdi = Sustraccion(app.imgPro, app.typePro, app.imgSeg, app.typeSeg);
            
                % Actualizar la imagen anterior para usar deshacer
                app.imgDes = app.imgPro;
                app.mapDes = app.mapPro;
                app.typeDes = app.typePro;
            
                % Cargar los cambios en la imagen procesada y mostrarla en la GUI
                app.imgPro = imgAdi;
                imshow(app.imgPro, 'Parent', app.UIAxes2);
            
            catch ME
                % Manejar errores específicos
                if strcmp(ME.identifier, 'Image:TypeError')
                    % Manejar el error de tipo de imagen no admitido
                    errordlg('Operación no válida para imágenes indexadas.', 'Error');
                else
                    % Otros errores no manejados específicamente
                    errordlg(ME.message, 'Error');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.AbrirArchivoMenu = uimenu(app.ArchivoMenu);
            app.AbrirArchivoMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirArchivo, true);
            app.AbrirArchivoMenu.Accelerator = 'o';
            app.AbrirArchivoMenu.Text = 'Abrir Archivo';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenuSelected, true);
            app.GuardarMenu.Accelerator = 's';
            app.GuardarMenu.Text = 'Guardar';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OperacionesBsicasMenu = uimenu(app.Menu);
            app.OperacionesBsicasMenu.Text = 'Operaciones Básicas';
            app.EcualizacionMenu = uimenu(app.OperacionesBsicasMenu);
            app.EcualizacionMenu.Text = 'Ecualización';
            app.EcualizarMenu = uimenu(app.EcualizacionMenu);
            app.EcualizarMenu.MenuSelectedFcn = createCallbackFcn(app, @EcualizarMenuSelected, true);
            app.EcualizarMenu.Text = 'Ecualizar';
            app.MostrarhistogramasMenu = uimenu(app.EcualizacionMenu);
            app.MostrarhistogramasMenu.MenuSelectedFcn = createCallbackFcn(app, @MostrarhistogramasMenuSelected, true);
            app.MostrarhistogramasMenu.Text = 'Mostrar histogramas';
            app.InversinMenu = uimenu(app.OperacionesBsicasMenu);
            app.InversinMenu.Text = 'Inversión';
            app.BinariaMenu = uimenu(app.InversinMenu);
            app.BinariaMenu.MenuSelectedFcn = createCallbackFcn(app, @BinariaMenuSelected, true);
            app.BinariaMenu.Text = 'Binaria';
            app.FotogrficaMenu = uimenu(app.InversinMenu);
            app.FotogrficaMenu.MenuSelectedFcn = createCallbackFcn(app, @FotogrficaMenuSelected, true);
            app.FotogrficaMenu.Text = 'Fotográfica';
            app.AdicinMenu = uimenu(app.OperacionesBsicasMenu);
            app.AdicinMenu.MenuSelectedFcn = createCallbackFcn(app, @AdicionMenuSelected, true);
            app.AdicinMenu.Text = 'Adición';
            app.SustraccinMenu = uimenu(app.OperacionesBsicasMenu);
            app.SustraccinMenu.MenuSelectedFcn = createCallbackFcn(app, @SustraccinMenuSelected, true);
            app.SustraccinMenu.Text = 'Sustracción';
            app.EspejoMenu = uimenu(app.Menu);
            app.EspejoMenu.MenuSelectedFcn = createCallbackFcn(app, @EspejoMenuSelected, true);
            app.EspejoMenu.Text = 'Espejo';
            app.FiltrosMenu = uimenu(app.Menu);
            app.FiltrosMenu.Text = 'Filtros';
            app.FiltradoEspacialOrdenEstadsticoMenu = uimenu(app.FiltrosMenu);
            app.FiltradoEspacialOrdenEstadsticoMenu.Text = 'Filtrado Espacial: Orden Estadístico';
            app.MedianaMenu = uimenu(app.FiltradoEspacialOrdenEstadsticoMenu);
            app.MedianaMenu.MenuSelectedFcn = createCallbackFcn(app, @MedianaMenuSelected, true);
            app.MedianaMenu.Text = 'Mediana';
            app.ModaMenu = uimenu(app.FiltradoEspacialOrdenEstadsticoMenu);
            app.ModaMenu.MenuSelectedFcn = createCallbackFcn(app, @ModaMenuSelected, true);
            app.ModaMenu.Text = 'Moda';
            app.MximoMenu = uimenu(app.FiltradoEspacialOrdenEstadsticoMenu);
            app.MximoMenu.MenuSelectedFcn = createCallbackFcn(app, @MximoMenuSelected, true);
            app.MximoMenu.Text = 'Máximo';
            app.MnimoMenu = uimenu(app.FiltradoEspacialOrdenEstadsticoMenu);
            app.MnimoMenu.MenuSelectedFcn = createCallbackFcn(app, @MnimoMenuSelected, true);
            app.MnimoMenu.Text = 'Mínimo';
            app.OrdenNMenu = uimenu(app.FiltradoEspacialOrdenEstadsticoMenu);
            app.OrdenNMenu.MenuSelectedFcn = createCallbackFcn(app, @OrdenNMenuSelected, true);
            app.OrdenNMenu.Text = 'Orden N';
            app.FiltradoEspacialPasoBajasMenu = uimenu(app.FiltrosMenu);
            app.FiltradoEspacialPasoBajasMenu.Text = 'Filtrado Espacial: Paso Bajas';
            app.CajaoMediaMenu = uimenu(app.FiltradoEspacialPasoBajasMenu);
            app.CajaoMediaMenu.MenuSelectedFcn = createCallbackFcn(app, @CajaoMediaMenuSelected, true);
            app.CajaoMediaMenu.Text = 'Caja o Media';
            app.GaussianoMenu = uimenu(app.FiltradoEspacialPasoBajasMenu);
            app.GaussianoMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianoMenuSelected, true);
            app.GaussianoMenu.Text = 'Gaussiano';
            app.FiltradoEspacialPasoAltasMenu = uimenu(app.FiltrosMenu);
            app.FiltradoEspacialPasoAltasMenu.Text = 'Filtrado Espacial: Paso Altas';
            app.LaplacianoMenu = uimenu(app.FiltradoEspacialPasoAltasMenu);
            app.LaplacianoMenu.Text = 'Laplaciano';
            app.VecinosMenu = uimenu(app.LaplacianoMenu);
            app.VecinosMenu.MenuSelectedFcn = createCallbackFcn(app, @VecinosMenuSelected, true);
            app.VecinosMenu.Text = '4 Vecinos';
            app.VecinosMenu_2 = uimenu(app.LaplacianoMenu);
            app.VecinosMenu_2.MenuSelectedFcn = createCallbackFcn(app, @VecinosMenu_2Selected, true);
            app.VecinosMenu_2.Text = '8 Vecinos';
            app.DeteccindecontornosMenu = uimenu(app.FiltrosMenu);
            app.DeteccindecontornosMenu.Text = 'Detección de contornos';
            app.PrewittMenu = uimenu(app.DeteccindecontornosMenu);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @PrewittMenuSelected, true);
            app.PrewittMenu.Text = 'Prewitt';
            app.SobelMenu = uimenu(app.DeteccindecontornosMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.RobertsMenu = uimenu(app.DeteccindecontornosMenu);
            app.RobertsMenu.MenuSelectedFcn = createCallbackFcn(app, @RobertsMenuSelected, true);
            app.RobertsMenu.Text = 'Roberts';
            app.ModificaciondelColordeOjosMenu = uimenu(app.Menu);
            app.ModificaciondelColordeOjosMenu.Text = 'Modificacion del Color de Ojos';
            app.AyudaMenu = uimenu(app.UIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.RotarIzquierda = uipushtool(app.Toolbar);
            app.RotarIzquierda.Tooltip = {'Rotar a la izquierda'};
            app.RotarIzquierda.ClickedCallback = createCallbackFcn(app, @RotarIzquierdaClicked, true);
            app.RotarIzquierda.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Derecha.png');
            app.RotarDerecha = uipushtool(app.Toolbar);
            app.RotarDerecha.Tooltip = {'Rotar a la derecha'};
            app.RotarDerecha.ClickedCallback = createCallbackFcn(app, @RotarDerechaClicked, true);
            app.RotarDerecha.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Izquierda.png');
            app.Deshacer = uipushtool(app.Toolbar);
            app.Deshacer.Tooltip = {'Deshacer'};
            app.Deshacer.ClickedCallback = createCallbackFcn(app, @DeshacerClicked, true);
            app.Deshacer.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Deshacer.png');
            app.DeshacerTodo = uipushtool(app.Toolbar);
            app.DeshacerTodo.Tooltip = {'Deshacer todo'};
            app.DeshacerTodo.ClickedCallback = createCallbackFcn(app, @DeshacerTodoClicked, true);
            app.DeshacerTodo.Icon = fullfile(pathToMLAPP, 'Imagenes', 'DeshacerTodo.png');
            app.QuitarImagen = uipushtool(app.Toolbar);
            app.QuitarImagen.Tooltip = {'Quitar imagen'};
            app.QuitarImagen.ClickedCallback = createCallbackFcn(app, @QuitarImagenClicked, true);
            app.QuitarImagen.Icon = fullfile(pathToMLAPP, 'Imagenes', 'QuitarImagen.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EdicinprincipalTab = uitab(app.TabGroup);
            app.EdicinprincipalTab.Title = 'Edición principal';
            app.GridLayout = uigridlayout(app.EdicinprincipalTab);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Segunda Imagen Original (Adicion/Sustraccion)')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Imagen Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'prism')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Imagen Procesada')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'prism')
            app.ConvertirDropDownLabel = uilabel(app.GridLayout);
            app.ConvertirDropDownLabel.Text = 'Convertir:';
            app.ConvertirDropDown = uidropdown(app.GridLayout);
            app.ConvertirDropDown.Items = {'Selecciona El Tipo', 'Binaria', 'Escala de Grises', 'RGB', 'Indexada'};
            app.ConvertirDropDown.ValueChangedFcn = createCallbackFcn(app, @CambioDeFormato, true);
            app.ConvertirDropDown.Value = 'Selecciona El Tipo';
            app.GeneracindecollageTab = uitab(app.TabGroup);
            app.GeneracindecollageTab.Title = 'Generación de collage';
            app.GridLayout2 = uigridlayout(app.GeneracindecollageTab);
            app.UIAxesResCollage = uiaxes(app.GridLayout2);
            title(app.UIAxesResCollage, 'Resultado collage')
            xlabel(app.UIAxesResCollage, 'X')
            ylabel(app.UIAxesResCollage, 'Y')
            zlabel(app.UIAxesResCollage, 'Z')
            app.UIAxesImg8Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg8Collage, 'Imagen 8')
            xlabel(app.UIAxesImg8Collage, 'X')
            ylabel(app.UIAxesImg8Collage, 'Y')
            zlabel(app.UIAxesImg8Collage, 'Z')
            app.UIAxesImg7Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg7Collage, 'Imagen 7')
            xlabel(app.UIAxesImg7Collage, 'X')
            ylabel(app.UIAxesImg7Collage, 'Y')
            zlabel(app.UIAxesImg7Collage, 'Z')
            app.UIAxesImg6Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg6Collage, 'Imagen 6')
            xlabel(app.UIAxesImg6Collage, 'X')
            ylabel(app.UIAxesImg6Collage, 'Y')
            zlabel(app.UIAxesImg6Collage, 'Z')
            app.UIAxesImg5Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg5Collage, 'Imagen 5')
            xlabel(app.UIAxesImg5Collage, 'X')
            ylabel(app.UIAxesImg5Collage, 'Y')
            zlabel(app.UIAxesImg5Collage, 'Z')
            app.UIAxesImg4Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg4Collage, 'Imagen 4')
            xlabel(app.UIAxesImg4Collage, 'X')
            ylabel(app.UIAxesImg4Collage, 'Y')
            zlabel(app.UIAxesImg4Collage, 'Z')
            app.UIAxesImg3Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg3Collage, 'Imagen 3')
            xlabel(app.UIAxesImg3Collage, 'X')
            ylabel(app.UIAxesImg3Collage, 'Y')
            zlabel(app.UIAxesImg3Collage, 'Z')
            app.UIAxesImg2Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg2Collage, 'Imagen 2')
            xlabel(app.UIAxesImg2Collage, 'X')
            ylabel(app.UIAxesImg2Collage, 'Y')
            zlabel(app.UIAxesImg2Collage, 'Z')
            app.UIAxesImg1Collage = uiaxes(app.GridLayout2);
            title(app.UIAxesImg1Collage, 'Imagen 1')
            xlabel(app.UIAxesImg1Collage, 'X')
            ylabel(app.UIAxesImg1Collage, 'Y')
            zlabel(app.UIAxesImg1Collage, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplicacionGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end